classdef MDD_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ProbabilitySliderLabel  matlab.ui.control.Label
        ProbabilitySlider       matlab.ui.control.Slider
        EditFieldLabel          matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        PathLengthLabel         matlab.ui.control.Label
        ConfidenceLevelLabel    matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        FrequencyDropDownLabel  matlab.ui.control.Label
        FrequencyDropDown       matlab.ui.control.DropDown
        FrequencyLabel          matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        AutoCorrSliderLabel     matlab.ui.control.Label
        AutoCorrSlider          matlab.ui.control.Slider
        SimulatedDataLabel      matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UITable                 matlab.ui.control.Table
        TableofConditionalExpectedDrawdownsLabel  matlab.ui.control.Label
        UIAxes2                 matlab.ui.control.UIAxes
        Button                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Create a public property to get the data from the workspace (to
    % change for repruductibility)
    
    properties (Access = public)
        Data = evalin("base",'Data.mkt') % Get Market Data From the Base workspace
        Date = evalin("base",'Data.date') % Get Date from the base workspace
        SimulatedCED = evalin("base",'Data.CED_SIMULATED') % Get simulated CED
        SimulatedDD =  evalin("base",'Data.MDD_Simulated') % Get simulated Drawdown
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Process)

            MaxDraw = MDD_Distribution(app.Data,125);
            [Quant, CondExp] = CED(MaxDraw, 0.5);
            
            % First plot
            histogram(app.UIAxes,MaxDraw,'Normalization',"probability")
            xline(app.UIAxes,Quant,'.-b')
            xline(app.UIAxes,CondExp,'.-r')
            legend(app.UIAxes,'MDD Distribution',sprintf('%s quantile',string(50)+'%'),...
                sprintf('CED at %s',string(50) + '%'))
            title(app.UIAxes,sprintf('MDD Distribution for a path of length %s days',string(int16(125))))
            xlabel(app.UIAxes,'Maximum Drawdown')
            ylabel(app.UIAxes,'Frequency')
            
           
            % Second plot
            plot(app.UIAxes_2,app.Date(app.EditField.Value:end),MaxDraw)
            legend(app.UIAxes_2,'Maximum DrawDown')
            title(app.UIAxes_2,sprintf('Rolling %s days Maximum DrawDown',string(int16(125))))
            xlabel(app.UIAxes_2,'Date')
            ylabel(app.UIAxes_2,'Maximum Drawdown')
            
            % Plotting the process
            Price = cumprod(1+app.Data);
            semilogy(app.UIAxes2,app.Date,Price)
            legend(app.UIAxes2,'Process','location','southeast')
            title(app.UIAxes2, 'Price process')
            xlabel(app.UIAxes2,'Date')
            ylabel(app.UIAxes2,'Cumulative Return')
            

            % Computing table values
            CondExpTable = zeros(3,3);
            count = 1;
            for i = [0.5,0.55,0.45]
            
                MaxDraw = MDD_Distribution(app.Data,125);
                [~,CondExpTable(1,count)] = CED(MaxDraw, i);
           
                MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,1),125);
                [~,CondExpTable(2,count)] = CED(MaxDraw, i);
            
                MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,2),125);
                [~,CondExpTable(3,count)] = CED(MaxDraw, i);
                
                count = count  + 1;
            end
            
            app.UITable.Data = CondExpTable;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {585, 585};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {178, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ProbabilitySlider
        function ProbabilitySliderValueChanged(app, event)
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           
            %Display computations
           
            if app.DropDown.Value == string(app.DropDown.Items(1))
                
            
                if app.FrequencyDropDown.Value == string(app.FrequencyDropDown.Items(1))
                    
                    % Compute distribution at Daily Frequency
                    MaxDraw = MDD_Distribution(app.Data,app.EditField.Value);
                    [Quant, CondExp] = CED(MaxDraw, app.ProbabilitySlider.Value);
                    
                    % Compute the cumReturn at daily Frequency
                    Price = cumprod(1+app.Data);
                    
                    % Plotting the CED
                    histogram(app.UIAxes,MaxDraw,'Normalization',"probability")
                    xline(app.UIAxes,Quant,'.-b')
                    xline(app.UIAxes,CondExp,'.-r')
                    legend(app.UIAxes,'MDD Distribution',sprintf('%s quantile',string(app.ProbabilitySlider.Value*100)+'%'),...
                        sprintf('CED at %s',string(app.ProbabilitySlider.Value*100) + '%'))
                    title(app.UIAxes,sprintf('MDD Distribution for a path of length %s days',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes,'Maximum Drawdown')
                    ylabel(app.UIAxes,'Frequency')
                    
                    % Plotting the DrawDown
                    plot(app.UIAxes_2, app.Date(app.EditField.Value:end), MaxDraw)
                    legend(app.UIAxes_2,'Maximum DrawDown')
                    title(app.UIAxes_2,sprintf('Rolling %s days Maximum DrawDown',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes_2,'Date')
                    ylabel(app.UIAxes_2,'Maximum Drawdown')
                    
                    % Plotting the process
                    semilogy(app.UIAxes2,app.Date,Price)
                    legend(app.UIAxes2,'Process','location','southeast')
                    title(app.UIAxes2, 'Price process')
                    xlabel(app.UIAxes2,'Date')
                    ylabel(app.UIAxes2,'Cumulative Return')
                    
                    % Computing table values
                    CondExpTable = zeros(3,3);
                    count = 1;
                    for i = [app.ProbabilitySlider.Value, app.ProbabilitySlider.Value + 0.05,app.ProbabilitySlider.Value - 0.05]
                    
                        if i < 0
                            
                            i = 0.01;
                            
                        elseif i > 1
                            
                            i = 0.99;
                        end
                        
                        MaxDraw = MDD_Distribution(app.Data,app.EditField.Value);
                        [~,CondExpTable(1,count)] = CED(MaxDraw, i);
                   
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,1),round(app.EditField.Value/5));
                        [~,CondExpTable(2,count)] = CED(MaxDraw, i);
                    
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,2),round(app.EditField.Value/20));
                        [~,CondExpTable(3,count)] = CED(MaxDraw, i);
                        
                        count = count  + 1;
                    end
                    
                    app.UITable.Data = CondExpTable;
                    
                    
                elseif app.FrequencyDropDown.Value == string(app.FrequencyDropDown.Items(2))
                    
                    % Compute distribution at weekly Frequency
                    WeeklyReturns = FrequencyConverter(app.Data,1);
                    
                    %Computing Week Dates
                    weekdate = DateConverter(app.Date,1);
                    
                    % Compute distribution at Daily Frequency
                    MaxDraw = MDD_Distribution(WeeklyReturns,app.EditField.Value);
                    [Quant, CondExp] = CED(MaxDraw, app.ProbabilitySlider.Value);
                    
                    % Compute the price process
                    Price = cumprod(1+WeeklyReturns);
                    
                    % Plotting the CED
                    histogram(app.UIAxes,MaxDraw,'Normalization',"probability")
                    xline(app.UIAxes,Quant,'.-b')
                    xline(app.UIAxes,CondExp,'.-r')
                    legend(app.UIAxes,'MDD Distribution',sprintf('%s quantile',string(app.ProbabilitySlider.Value*100)+'%'),...
                        sprintf('CED at %s',string(app.ProbabilitySlider.Value*100) + '%'))
                    title(app.UIAxes,sprintf('MDD Distribution for a path of length %s weeks',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes,'Maximum Drawdown')
                    ylabel(app.UIAxes,'Frequency')
                    
                    % Plotting the DrawDown
                    plot(app.UIAxes_2, weekdate(app.EditField.Value:end), MaxDraw)
                    legend(app.UIAxes_2,'Maximum DrawDown')
                    title(app.UIAxes_2,sprintf('Rolling %s weeks Maximum DrawDown',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes_2,'Date')
                    ylabel(app.UIAxes_2,'Maximum Drawdown')
                    
                    % Plotting the process
                    semilogy(app.UIAxes2,weekdate,Price)
                    legend(app.UIAxes2,'Process','location','southeast')
                    title(app.UIAxes2, 'Price process')
                    xlabel(app.UIAxes2,'Date')
                    ylabel(app.UIAxes2,'Cumulative Return')
                    
                    % Computing table values
                    CondExpTable = zeros(3,3);
                    count = 1;
                    for i = [app.ProbabilitySlider.Value, app.ProbabilitySlider.Value + 0.05,app.ProbabilitySlider.Value - 0.05]
                    
                        if i < 0
                            
                            i = 0.01;
                            
                        elseif i > 1
                            
                            i = 0.99;
                        end
                        
                        MaxDraw = MDD_Distribution(app.Data,app.EditField.Value*5);
                        [~,CondExpTable(1,count)] = CED(MaxDraw, i);
                   
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,1),app.EditField.Value);
                        [~,CondExpTable(2,count)] = CED(MaxDraw, i);
                    
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,2),round(app.EditField.Value/4));
                        [~,CondExpTable(3,count)] = CED(MaxDraw, i);
                        
                        count = count  + 1;
                    end
                    
                    app.UITable.Data = CondExpTable;
                    
                else
                    
                    % Compute distribution at monthly Freqeuncy
                    MonthlyReturns = FrequencyConverter(app.Data,2);
                    
                    %Computing Month Dates
                    monthdate = DateConverter(app.Date,2);
                    
                    % Compute distribution at Daily Frequency
                    MaxDraw = MDD_Distribution(MonthlyReturns,app.EditField.Value);
                    [Quant, CondExp] = CED(MaxDraw, app.ProbabilitySlider.Value);
                    
                    % Compute the price process
                    Price = cumprod(1+MonthlyReturns);
                    
                    % Plotting the CED
                    histogram(app.UIAxes,MaxDraw,'Normalization',"probability")
                    xline(app.UIAxes,Quant,'.-b')
                    xline(app.UIAxes,CondExp,'.-r')
                    legend(app.UIAxes,'MDD Distribution',sprintf('%s quantile',string(app.ProbabilitySlider.Value*100)+'%'),...
                        sprintf('CED at %s',string(app.ProbabilitySlider.Value*100) + '%'))
                    title(app.UIAxes,sprintf('MDD Distribution for a path of length %s months',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes,'Maximum Drawdown')
                    ylabel(app.UIAxes,'Frequency')
                    
                    % Plotting the DrawDown
                    plot(app.UIAxes_2, monthdate(app.EditField.Value:end), MaxDraw)
                    legend(app.UIAxes_2,'Maximum DrawDown')
                    title(app.UIAxes_2,sprintf('Rolling %s month Maximum DrawDown',string(int16(app.EditField.Value))))
                    xlabel(app.UIAxes_2,'Date')
                    ylabel(app.UIAxes_2,'Maximum Drawdown')                
                   
                    % Plotting the process
                    semilogy(app.UIAxes2,monthdate,Price)
                    legend(app.UIAxes2,'Process','location','southeast')
                    title(app.UIAxes2, 'Price process')
                    xlabel(app.UIAxes2,'Date')
                    ylabel(app.UIAxes2,'Cumulative Return')
                    
                    % Computing table values
                    CondExpTable = zeros(3,3);
                    count = 1;
                    for i = [app.ProbabilitySlider.Value, app.ProbabilitySlider.Value + 0.05,app.ProbabilitySlider.Value - 0.05]
                    
                        if i < 0
                            
                            i = 0.01;
                            
                        elseif i > 1
                            
                            i = 0.99;
                        end
                        
                        MaxDraw = MDD_Distribution(app.Data,app.EditField.Value*20);
                        [~,CondExpTable(1,count)] = CED(MaxDraw, i);
                   
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,1),app.EditField.Value*5);
                        [~,CondExpTable(2,count)] = CED(MaxDraw, i);
                    
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,2),app.EditField.Value);
                        [~,CondExpTable(3,count)] = CED(MaxDraw, i);
                        
                        count = count  + 1;
                    end
                    
                    app.UITable.Data = CondExpTable;
                    
                end
                
            else
                
                Nspace = linspace(0,1,101);
                Index = find(Nspace == round(app.AutoCorrSlider.Value,2));
                % Plotting the CED
                histogram(app.UIAxes,app.SimulatedDD(:,Index),'Normalization',"probability")
                %xline(app.UIAxes,Quant,'.-b')
                xline(app.UIAxes,app.SimulatedCED(Index),'.-r')
                legend(app.UIAxes,'MDD Distribution',...
                        sprintf('CED at %s',string(5) + '%'))
                title(app.UIAxes,sprintf('MDD Distribution for a path of length %s days',string(int16(125))))
                xlabel(app.UIAxes,'Maximum Drawdown')
                ylabel(app.UIAxes,'Frequency')
                    
                % Plotting the DrawDown
                plot(app.UIAxes_2, app.SimulatedDD(:,Index))
                legend(app.UIAxes_2,'Maximum DrawDown')
                title(app.UIAxes_2,sprintf('Rolling %s days Maximum DrawDown',string(int16(125))))
                xlabel(app.UIAxes_2,'Date')
                ylabel(app.UIAxes_2,'Maximum Drawdown')
                    
                % Plotting the process
                plot(app.UIAxes2,Nspace(1:end-1),app.SimulatedCED(1:end-1),'.-r')
                legend(app.UIAxes2,'CED','location','southeast')
                title(app.UIAxes2, 'CED at 5% for different Auto-correlation level')
                xlabel(app.UIAxes2,'Level of Auto-correlation')
                ylabel(app.UIAxes2,'CED at 5%')
                    
                % Computing table values
                CondExpTable = zeros(3,3);
                count = 1;
                    for i = [0.05, 0.01,0.1]
                        
                        MaxDraw = MDD_Distribution(app.Data,125);
                        [~,CondExpTable(1,count)] = CED(MaxDraw, i);
                   
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,1),round(25));
                        [~,CondExpTable(2,count)] = CED(MaxDraw, i);
                    
                        MaxDraw = MDD_Distribution(FrequencyConverter(app.Data,2),round(6));
                        [~,CondExpTable(3,count)] = CED(MaxDraw, i);
                        
                        count = count  + 1;
                    end
                    
                    app.UITable.Data = CondExpTable;
                
            end
           
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'MDD Interface';
            app.ProbabilitySliderLabel = uilabel(app.LeftPanel);
            app.ProbabilitySliderLabel.Text = 'Probability';
            app.ProbabilitySlider = uislider(app.LeftPanel);
            app.ProbabilitySlider.Limits = [0 1];
            app.ProbabilitySlider.ValueChangedFcn = createCallbackFcn(app, @ProbabilitySliderValueChanged, true);
            app.ProbabilitySlider.Value = 0.5;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 125;
            app.PathLengthLabel = uilabel(app.LeftPanel);
            app.PathLengthLabel.Text = 'Path Length';
            app.ConfidenceLevelLabel = uilabel(app.LeftPanel);
            app.ConfidenceLevelLabel.Text = 'Confidence Level';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FrequencyDropDownLabel = uilabel(app.LeftPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.LeftPanel);
            app.FrequencyDropDown.Items = {'Daily', 'Weekly', 'Monthly', ''};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = 'Daily';
            app.FrequencyLabel = uilabel(app.LeftPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'This interface allows the user '; 'to display the rolling maximum'; 'drawdown over a path of "Path'; 'Lenght" and the conditional '; 'expected drawdown for the '; 'chosen confidence level. '; 'One need to be careful, the '; '"Path Length" is in the unit of '; '"Frequency".'};
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Real Data', 'Simulated Data'};
            app.DropDown.Value = 'Real Data';
            app.AutoCorrSliderLabel = uilabel(app.LeftPanel);
            app.AutoCorrSliderLabel.Text = 'AutoCorr';
            app.AutoCorrSlider = uislider(app.LeftPanel);
            app.AutoCorrSlider.Limits = [0 1];
            app.AutoCorrSlider.Value = 0.5;
            app.SimulatedDataLabel = uilabel(app.LeftPanel);
            app.SimulatedDataLabel.Text = 'Simulated Data ?';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'You can also choose simulated '; 'AR1 process to see the '; 'effect of auto-correlation.'; 'The simulation are done '; 'with 125 days and a level of'; ' confidence of 5%'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Alpha'; 'Alpha + 5%'; 'Alpha - 5%'};
            app.UITable.RowName = {'Daily'; 'Weekly'; 'Monthly'};
            app.TableofConditionalExpectedDrawdownsLabel = uilabel(app.RightPanel);
            app.TableofConditionalExpectedDrawdownsLabel.Text = 'Table of Conditional Expected Drawdowns';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.Icon = '330px-Logo_HEC_Lausanne.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MDD_APP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end